classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        ExamplesTab           matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
        ExamplesListBox       matlab.ui.control.ListBox
        ExamplesListBoxLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        Tab2                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        examples;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.examples = getExamples();
        end

        % Value changed function: ExamplesListBox
        function ExamplesListBoxValueChanged(app, event)
            idx = app.ExamplesListBox.Value;
            numericIdx = str2double(idx);
            app.UITable.Data = app.examples{numericIdx};
            x_scale_values = 20;
            errors = cbSolveErrorForA(app.examples{numericIdx}, x_scale_values, 10);
            plot(app.UIAxes, 1:x_scale_values, errors);
            xlabel(app.UIAxes, 'x_scale');
            ylabel(app.UIAxes, 'Error');
            title(app.UIAxes, 'Error vs. x_scale');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExamplesTab = uitab(app.TabGroup);
            app.ExamplesTab.Title = 'Examples';
            app.UIAxes = uiaxes(app.ExamplesTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ExamplesListBoxLabel = uilabel(app.ExamplesTab);
            app.ExamplesListBoxLabel.Text = 'Examples';
            app.ExamplesListBox = uilistbox(app.ExamplesTab);
            app.ExamplesListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.ExamplesListBox.ValueChangedFcn = createCallbackFcn(app, @ExamplesListBoxValueChanged, true);
            app.ExamplesListBox.Value = '1';
            app.UITable = uitable(app.ExamplesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end